classdef TIQA_CNSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CustomizedFeatureCombinationTab  matlab.ui.container.Tab
        trainButton_2                 matlab.ui.control.Button
        QualityPredictionButton       matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        ScoreEditField_3              matlab.ui.control.EditField
        ScoreEditField_3Label         matlab.ui.control.Label
        ScheduleEditField_3           matlab.ui.control.EditField
        ScheduleEditField_3Label      matlab.ui.control.Label
        FeatureSelectionPanel_2       matlab.ui.container.Panel
        DarkChannelCheckBox_2         matlab.ui.control.CheckBox
        PhaseCongruencyCheckBox_2     matlab.ui.control.CheckBox
        VisualNeuronMatrixCheckBox_2  matlab.ui.control.CheckBox
        GlobalContrastCheckBox_2      matlab.ui.control.CheckBox
        EntropyCheckBox_2             matlab.ui.control.CheckBox
        LocalBinaryPatternCheckBox_2  matlab.ui.control.CheckBox
        MeanSubtractedContrastNormalizedCheckBox_2  matlab.ui.control.CheckBox
        GeneralizedGaussianDistributionCheckBox_2  matlab.ui.control.CheckBox
        AsymmetricGeneralizedGaussianDistributionCheckBox_2  matlab.ui.control.CheckBox
        EnergyDifferenceCheckBox_2    matlab.ui.control.CheckBox
        UIAxes_3                      matlab.ui.control.UIAxes
        RecommendedFeatureCombinationTab  matlab.ui.container.Tab
        DefaultSelectionLabel         matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        OPTION1Node                   matlab.ui.container.TreeNode
        AsymmetricGeneralizedGaussianDistributionNode_2  matlab.ui.container.TreeNode
        LocalBinaryPatternNode_2      matlab.ui.container.TreeNode
        OPTION2Node                   matlab.ui.container.TreeNode
        EntropyNode                   matlab.ui.container.TreeNode
        GlobalContrastNode            matlab.ui.container.TreeNode
        EnergyDifferenceNode          matlab.ui.container.TreeNode
        AsymmetricGeneralizedGaussianDistributionNode  matlab.ui.container.TreeNode
        LocalBinaryPatternNode        matlab.ui.container.TreeNode
        PhaseCongruencyNode           matlab.ui.container.TreeNode
        OPTION3Node                   matlab.ui.container.TreeNode
        VisualNeuronMatrixNode        matlab.ui.container.TreeNode
        OPTION4Node                   matlab.ui.container.TreeNode
        GeneralizedGaussianDistributionNode  matlab.ui.container.TreeNode
        AsymmetricGeneralizedGaussianDistributionNode_3  matlab.ui.container.TreeNode
        MeanSubtractedContrastNormalizedNode  matlab.ui.container.TreeNode
        ScoreEditField                matlab.ui.control.EditField
        ScoreEditFieldLabel           matlab.ui.control.Label
        QualityPredictionButton_2     matlab.ui.control.Button
        LoadImageButton_2             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        DirectlyUsableIQAMethodsTab   matlab.ui.container.Tab
        IQAMethodButtonGroup          matlab.ui.container.ButtonGroup
        DIBRButton                    matlab.ui.control.RadioButton
        MEONButton                    matlab.ui.control.RadioButton
        GWHGLBPButton                 matlab.ui.control.RadioButton
        ILNIQEButton                  matlab.ui.control.RadioButton
        OGIQAButton                   matlab.ui.control.RadioButton
        dipIQButton                   matlab.ui.control.RadioButton
        DBCNNButton                   matlab.ui.control.RadioButton
        PIQUEButton                   matlab.ui.control.RadioButton
        NIQEButton                    matlab.ui.control.RadioButton
        BRISQUEButton                 matlab.ui.control.RadioButton
        QualityPredictionButton_3     matlab.ui.control.Button
        ScoreEditField_2              matlab.ui.control.EditField
        ScoreEditField_2Label         matlab.ui.control.Label
        LoadImageButton_3             matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        im % Description %app1
        Score = 0 % app1
        ScoreTest = 0 % app3 train and test
        im2 % Distorted Image % app2
        im3 % test Distorted Image app3
        Score2 = 0 % app2
        Feature = [] % feature
    end
    
    methods (Access = public)
        
        function [feature] = getfeature(app, im)
            L=3; 
        R = 1; P = 8;MAP= 3;
        
            featureSize = 64; % 预先定义特征数组的大小
        
            % 预先分配特征数组
            feature = zeros(1, featureSize);
        
            im=double(rgb2gray(im));   %将图像转换为灰度图，将图像的数据格式转换为double型的
            %imshow(im)
            [x,y]=size(im);
            x=x-mod(x,2^L);
            y=y-mod(y,2^L);
            im=im(1:x,1:y);
            
            %使用 dddtree2 函数创建一个复数域的多尺度金字塔，用于后续的AGGD特征提取
            wt=dddtree2('cplxdt',im,L,'FSfarras','qshift14');    
            wt.cfs{L+1}(:,:,:,:)=0;
            
            Z=idddtree2(wt);%Z=504*632
            
            %imshow(Z);
            for a=1:3%尺度
                for x=1:2%方向
                    for j=1:3
                        wb=wt.cfs{a}(:,:,j,x,1);
                        [feature(3*j-2+(a-1)*18+(x-1)*9), feature(3*j-1+(a-1)*18+(x-1)*9),feature(3*j+(a-1)*18+(x-1)*9)]=estimateaggdparam(app,wb(:));
                    end
                end
            end 
            hist1=LBP_test(app,Z,R,P,MAP);
            
            feature(55:64)=hist1;
            
        end
        
        function [ hist1] = LBP_test(app, x,R,P,y)
            lbp_type = { 'ri' 'u2' 'riu2' };
            %       'u2'   for uniform LBP
            %       'ri'   for rotation-invariant LBP
            %       'riu2' for uniform rotation-invariant LBP.
            mtype = lbp_type{y}; 
            
            MAPPING = getmapping(app, P, mtype );
            LBPMap = lbp(app, x,R,P,MAPPING,'x');
            %imshow(LBPMap);
            hist1=wlbp_feature(app,x,LBPMap);
        end
        
        function [feat] = wlbp_feature(app, im, LBPMap)
            feat = [];
            wLBPHist = [];%初始化一个空向量 wLBPHist，用于存储加权的LBP直方图
            weightmap = im;
            %imshow(weightmap);
            wintesity = weightmap(2:end-1, 2:end-1);
            wintensity = abs(wintesity);
            for k = 1:max(LBPMap(:))+1
                %idx = find(LBPMap == k-1);
                % 使用逻辑索引代替 find 函数
                idx = LBPMap == k-1; % 创建一个逻辑数组
                kval = sum(wintensity(idx));
                wLBPHist = [wLBPHist kval];
            end
    
            wLBPHist = wLBPHist/sum(wLBPHist);
            feat = [feat wLBPHist];
        end
        
        function [alpha leftstd rightstd] = estimateaggdparam(app, vec)
            gam   = 0.2:0.001:10;
            r_gam = ((gamma(2./gam)).^2)./(gamma(1./gam).*gamma(3./gam));
            
            
            leftstd            = sqrt(mean((vec(vec<0)).^2));
            rightstd           = sqrt(mean((vec(vec>0)).^2));
            gammahat           = leftstd/rightstd;
            rhat               = (mean(abs(vec)))^2/mean((vec).^2);
            rhatnorm           = (rhat*(gammahat^3 +1)*(gammahat+1))/((gammahat^2 +1)^2);
            [min_difference, array_position] = min((r_gam - rhatnorm).^2);
            alpha              = gam(array_position);
        end
        
        function [featureVector] = getFeatures(app, img)%提取特征
            %转换为灰度图像
            imgGray = rgb2gray(img);%原本
            
            %计算局部傅里叶描述符（LFD）
            FD = getFD(app,img);
            %LFD直方图统计,使用 histcounts 函数计算 FD 的概率密度直方图，直方图的边界在代码中定义
            [featureVector_1,~] = histcounts(FD(:), [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3], 'Normalization', 'probability');
            
            % 二维离散小波变换,使用 dwt2 函数对图像进行二维离散小波变换，得到水平、垂直和对角方向的系数
            [~,cH,cV,cD]=dwt2(img,'sym4','mode','per');
            % 小波系数的首位数字分布,计算小波系数的首位数字分布
            %featureVector_2 = getFirstDigitDistribution(cH);
            
            %featureVector_3 = getFirstDigitDistribution(cV);
            
            %featureVector_4 = getFirstDigitDistribution(cD);
            % 
            %梯度幅度,计算灰度图像的梯度幅度
            [Mag,~] = imgradient(imgGray);
            %计算梯度幅度的首位数字分布
            %featureVector_5 = getFirstDigitDistribution(Mag);
            
            %计算相位一致性图像
            PC = getPhaseCongruencyImage(app,img);%原本
            %imshow(PC);%显示相位一致性图像
            
            %计算颜色统计特征
            colorFeatures = getColorStatistics(app, img);
            featureVector = [getGlobalContrastFactor(app, img),getDarkChannelFeature(app,img), entropy(img), mean(PC(:)),entropy(PC), getColorStatistics(app, img)];
            featureVector = [featureVector,featureVector,featureVector,featureVector,featureVector,featureVector,featureVector];
        end
        
        function [FD] = getFD(app, img)
            if(size(img,3)==3)
                img = rgb2gray(img);
            end
        
            %获取灰度图像的行数（M）和列数（N）
            [M,N]= size(img);
        
            %初始化一个与图像大小相同的零矩阵，用于存储计算出的傅里叶描述符
            FD = zeros(M,N);
        
            %计算不同尺度下的局部特征
            for r=2:7
                rc = @(x) floor(((max(x)-min(x))/r))+ 1; % non-linear filter%rc 是一个匿名函数，用于计算每个像素在列方向上的非线性滤波器索引
                F= colfilt(img, [r r],'sliding', rc);%F 是通过 colfilt 函数应用非线性滤波器得到的滤波后图像
                B{r}= log(double(F * (49/(r^2))));%B{r} 存储了每个尺度下的滤波后图像的对数值
            end
        
            i=log(2:7);%i = [log(2), log(3), log(4), log(5), log(6), log(7)];
            %Nxx 是一个常数，用于后续计算中的正规化项
            Nxx=dot(i,i)-(sum(i)^2)/6;
        
            %嵌套循环遍历图像像素
            for m = 1:M
                for n = 1:N
                    fd= [B{7}(m,n), B{6}(m,n), B{5}(m,n), B{4}(m,n), B{3}(m,n), B{2}(m,n)]; % Number of boxes multiscale vector%fd 是一个多尺度特征向量，包含了从7到2（逆序）的尺度下对应像素点的对数值
                    Nxy=dot(i,fd)-(sum(i)*sum(fd))/6; %Nxy 是 fd 向量与尺度指数 i 的点积，减去它们各自和的点积除以6
                    FD(m, n)= (Nxy/Nxx); % slope of the linear regression line%FD(m, n) 计算每个像素点的傅里叶描述符，这是 Nxy 与 Nxx 的比值，可以理解为线性回归线的斜率
                end
            end
        end
        
        function [feature] = getDarkChannelFeature(app, img)
            JDark = darkChannel(app, img);
            [W,H] = size(JDark);
            feature = sum(JDark(:)) / (W*H);
        end
        
        function [colorFeatures] = getColorStatistics(app, imgRGB)
            if(isa(imgRGB,'uint8'))
                imgRGB = im2double(imgRGB);
            end
        
            %RGB图像分为三通道
            R = imgRGB(:,:,1);
            G = imgRGB(:,:,2);
            B = imgRGB(:,:,3);
            
            %获取图像尺寸：使用 size 函数获取图像的行数（M）、列数（N）和通道数
            [M, N, ~] = size(imgRGB);
            
            %避免对数运算中的零值
            for i=1:M
                for j=1:N
                    if(R(i,j)==0)
                        R(i,j)=0.0001;
                    end
                    if(G(i,j)==0)
                        G(i,j)=0.0001;
                    end
                    if(B(i,j)==0)
                        B(i,j)=0.0001;
                    end
                end
            end
            
            %对颜色通道应用对数变换，对R、G、B通道的每个像素值取自然对数
            logR = log(R);
            logG = log(G);
            logB = log(B);
            
            %中心化对数变换，将每个通道的对数变换结果减去其均值，以中心化数据
            R1 = logR - mean(logR(:));
            G1 = logG - mean(logG(:));
            B1 = logB - mean(logB(:));
            
            %线性组合生成新通道，通过R、G、B的线性组合生成三个新通道 l1、l2 和 l3。这些组合旨在提取不同颜色空间中的特征
            l1 = (R1 + G1+ B1)/(sqrt(3));
            l2 = (R1 + G1 - 2*B1)/(sqrt(6));
            l3 = (R1 - G1)/(sqrt(2));
            
            %拟合正态分布，使用 fitdist 函数对每个新通道的像素值拟合正态分布。这将为每个通道提供均值（mu）和标准差（sigma）
            pd1 = fitdist(l1(:),'Normal');
            pd2 = fitdist(l2(:),'Normal');
            pd3 = fitdist(l3(:),'Normal');
            
            %提取颜色特征，将三个通道的均值和标准差组合成一个向量 colorFeatures，作为图像的颜色统计特征
            colorFeatures = [pd1.mu, pd1.sigma, pd2.mu, pd2.sigma, pd3.mu, pd3.sigma];
        end
        
        function [GCF] = getGlobalContrastFactor(app, im)
            GCF = 0.0;
            resolutions = [1 2 4 8 16 25 50 100 200];
            LC = zeros(size(resolutions));
            
            W = size(im,2);
            H = size(im,1);
            
            rIm = im;
            
            for i=1:length(resolutions)
                
                %attempt at resizing as in the paper
                if i>1
                  rIm = imresize(im, 1/(2^(i-1)), 'bilinear');      
                end
        
                W = size(rIm,2);
                H = size(rIm,1);
                
                rL = zeros(size(rIm));
                % compute linear luminance l
                l = (double(rIm(:,:))/255) * 2.2;
        
                % compute perceptual luminance L
                rL(:,:) = 100 * sqrt(l);
                
                % compute local contrast for each pixel
                lc = 0.0;
                for x=1:H
                    for y=1:W
                        
                        if (x == 1) && (x == H)
                            if (y == 1) && (y == W)
                               lc = lc + 0;
                            elseif (y == 1)
                               lc = lc + abs(rL(x, y) - rL(x,y+1));
                            elseif (y == W)
                                lc = lc + abs(rL(x, y) - rL(x,y-1));
                            else
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                        abs(rL(x, y) - rL(x,y+1)) )/2;
                            end
                                        
                        elseif (x == 1)
                            if (y == 1) && (y == W) 
                                lc = lc + abs(rL(x, y) - rL(x+1,y));
                            elseif (y == 1) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y+1)) + ...
                                        abs(rL(x, y) - rL(x+1,y)) )/2;
                            elseif (y == W) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                        abs(rL(x, y) - rL(x+1,y)) )/2;
                            else 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                        abs(rL(x, y) - rL(x,y+1)) + ...
                                        abs(rL(x, y) - rL(x+1,y)) )/3;
                            end
                            
                        elseif (x == H)
                            if (y == 1) && (y == W)
                                lc = lc + abs(rL(x, y) - rL(x-1,y));
                            elseif (y == 1) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y+1)) + ...
                                        abs(rL(x, y) - rL(x-1,y)) )/2;
                            elseif (y == W) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                        abs(rL(x, y) - rL(x-1,y)) )/2;
                            else 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                            abs(rL(x, y) - rL(x,y+1)) + ...
                                            abs(rL(x, y) - rL(x-1,y)) )/3;
                            end
                        else % x > 1 && x < H
                            if (y == 1) && (y == W)
                                lc = lc + ( abs(rL(x, y) - rL(x+1,y)) + ...
                                        abs(rL(x, y) - rL(x-1,y)) )/2;
                            elseif (y == 1) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y+1)) + ...
                                            abs(rL(x, y) - rL(x+1,y)) + ...
                                            abs(rL(x, y) - rL(x-1,y)) )/3;
                            elseif (y == W) 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                            abs(rL(x, y) - rL(x+1,y)) + ...
                                            abs(rL(x, y) - rL(x-1,y)) )/3;
                            else 
                                lc = lc + ( abs(rL(x, y) - rL(x,y-1)) + ...
                                            abs(rL(x, y) - rL(x,y+1)) + ...
                                            abs(rL(x, y) - rL(x-1,y)) + ...
                                            abs(rL(x, y) - rL(x+1,y)) )/4;
                            end
                         end
                     end
                end
                
                    % compute average local contrast c
                    c(i) = lc/(W*H);
                    w(i) = (-0.406385*(i/9)+0.334573)*(i/9)+ 0.0877526;
                    
                    % compute global contrast factor
                    LC(i) = c(i)*w(i);
                    GCF = GCF + LC(i);
                
             end  
        end
        
        function [PC] = getPhaseCongruencyImage(app,I)
            [Y,X,ch] = size(I);

            if(ch==3)
                I = rgb2gray(I);
            end
            
            % This time we have to use exactly two scales, as this is required by
            % Felsberg's phase congruency method. As he suggests, let's use
            % three-ocatave filters and leave a three-octave spacing between them.
            % We want small filters to pick out the fine detail in this image.
            cw = [3,24];
        
            % Construct new filters, as before
            filtStruct = createMonogenicFilters(app,Y,X,cw,'lg',0.41);
        
            % Find monogenic signal, as before
            [m1,m2,m3] = monogenicSignal(app, I,filtStruct);
        
            % Now use the phase congruency algorithm. The fourth parameter is a
            % threshold between 0 and 1 used for noise supression. You will always need
            % to use this to get reasonable results. Somewhere between 0 and 0.1 should
            % do in most cases.
            PC = phaseCongruency(app, m1,m2,m3,0.05);
        end
        
        function filtStruct = createMonogenicFilters(app,ysize,xsize,wl,filtType,parameter)
            if nargin < 4 || isempty(filtType)%检查是否提供了filtType参数，如果没有提供，则默认为'lg'（Log-Gabor滤波器）
                filtType = 'lg';
            end
            
            % Frequency grid for the filter
            %生成滤波器的频率网格
            [yGrid,xGrid] = freqgrid2(app,ysize,xsize);
            
            % Determine the spatial regions to use
            %计算从原点到频率网格上每个点的距离
            w = sqrt(yGrid.^2 + xGrid.^2);
            %为了避免在直流分量（DC component，即频率为0的分量）处除以零，将原点处的w值设置为1
            w(1, 1) = 1;    %Trick to avoid division by zero at DC component
            
            % Determine the number of filters to try from input
            %确定要生成的带通滤波器的数量，即输入的波长向量wl的长度
            numFilt = length(wl);
            
            % Parameters governing the bandwidth of the bandpass filter for Gabor and
            % log-Gabor type filters, and ratio of centre frequencies for difference of
            % Poisson filters
            
            %sigmaOnf和ratio参数的设置：根据是否提供了parameter参数，设置滤波器的带宽和中心频率比
            if nargin > 4 && ~isempty(parameter)
                if(parameter < 0.0 || parameter > 1.0)
                    error('Parameter must be between 0.0 and 1.0');
                end
                sigmaOnf = parameter;
                ratio = parameter;
            else
                sigmaOnf = 0.5;
                ratio = 0.98;
            end
            
            % Output structure
            %初始化带通滤波器数组
            bpFilt = zeros(ysize,xsize,1,numFilt);
            
            
            % Generate band-pass filters (in frequency domain)
            %循环生成带通滤波器：对于每个波长，根据filtType参数的值，创建不同类型的带通滤波器
            for flt = 1:numFilt
                % Construct the filter - first calculate the radial filter component.
                fo = 1.0/wl(flt);   % Centre frequency of filter.
                w0 = fo;       % Normalised radius from centre of frequency plane
                                    % corresponding to fo.
                %对于每种滤波器类型，计算其在频率域的响应，并赋值给bpFilt数组
                if strcmp(filtType, 'lg')
                    bpFilt(:,:,1,flt) = exp((-(log(w/w0)).^2) / (2 * log(sigmaOnf)^2));         % computation of 3D log-gabor filter across the volumetric range
                elseif strcmp(filtType, 'gabor')
                    bpFilt(:,:,1,flt) = exp((-(w/w0).^2) / (2 * sigmaOnf^2));                   % gabor filter
                elseif strcmp(filtType, 'gd')
                    bpFilt(:,:,1,flt) = w .* exp(-(w.^2)*(wl(flt)^2));                          % isotropic gaussian derivative filter; wl is used for sigma
                elseif strcmp(filtType, 'cau')
                    bpFilt(:,:,1,flt) = w .* exp(-(w)*(wl(flt)));                               % cauchy filter, wl is used for sigma
                elseif strcmp(filtType, 'dop')
                    s2 = wl(flt)/((ratio-1))*log(ratio);                                   % difference of Poisson filters
                    s1 = ratio*s2;
                    bpFilt(:,:,1,flt) = exp(-w*s1) - exp(-w*s2);
                end
            
                % Set the DC value of the filter
                %将滤波器的直流分量(DC value,Direct Current Component)设置为0
                bpFilt(1, 1, 1, flt) = 0;
            
                % Also remove unwanted high frequency components in filters
                % with even dimensions
                %移除偶数尺寸滤波器的高频分量
                if(mod(ysize,2) == 0)
                   bpFilt( ysize/2 +1,:, 1, flt) = 0;
                end
                if(mod(xsize,2) == 0)
                   bpFilt( :, xsize/2 +1, 1, flt) = 0;
                end
            end
            
            % Normalise by the maximum value of the sum of all filters
            sumFilt = sum(bpFilt, 4);
            %filtStruct
            filtStruct.bpFilt = bpFilt ./ max(sumFilt(:));%归一化带通滤波器%一个四维数组，存储了每个波长的带通滤波器。第三维是单例维度，这意味着它可以用于直观地过滤图像堆栈（例如视频）
            filtStruct.numFilt = numFilt;%带通滤波器的数量
            
            % Generating the Riesz filter components as a complex filter
            filtStruct.ReiszFilt = (1i * yGrid - xGrid)./ w;     % (iY) + i(iX) = iY - X%一个复数值的滤波器，用于Riesz变换
            
            % Create a frequency-domain differentiation filter (using the
            % differentation property of Fourier Transforms)
            filtStruct.diffFilt = 2i*pi*yGrid - 2*pi*xGrid;          % (iY) + i(iX) = iY - X%一个频率域的微分滤波器
            
            %将波长、滤波器类型、带宽参数和中心频率比添加到输出结构体中
            filtStruct.wl = wl;%输入的波长向量
            filtStruct.filtType = filtType;%使用的滤波器类型
            filtStruct.sigmaOnf = sigmaOnf;%用于lg（Log-Gabor）和dop（Difference of Poisson）类型滤波器的形状参数
            filtStruct.ratio = ratio;
        end
        
        function [yGrid, xGrid] = freqgrid2(app, ysize,xsize)
            if nargin < 2
                xsize = ysize;
            end
            
            % Dimensions
            ymid = floor(ysize/2); 
            xmid = floor(xsize/2);
            
            % Work out the maximum frequency in the grid - depends on whether the image
            % has odd or even dimensions due to the definition of the FFT
            if(mod(ysize,2)) == 0
                ymax = ymid-1;
            else
                ymax = ymid;
            end
            
            if(mod(xsize,2)) == 0
                xmax = xmid-1;
            else
                xmax = xmid;
            end

            % Create the grid for the filter
            [yGrid, xGrid] = ndgrid( -ymid : ymax, -xmid : xmax);
            yGrid = ifftshift(yGrid)/ysize;%归一化
            xGrid = ifftshift(xGrid)/xsize;%归一化
        end
        
        function [Fm1, Fm2, Fm3] = monogenicSignal(app,im, filtStruct)
            F = fft2(im);
            Ffilt = bsxfun(@times, F, filtStruct.bpFilt);
            
            % Compute the parts of the monogenic signal (NB applying the ifft2 to a 3D
            % image here performs the 2D ifft to each two dimensional slice)
            Fm1 = real(ifft2( Ffilt ));    %even component
            Fmodd = ifft2( bsxfun(@times, Ffilt, filtStruct.ReiszFilt) );
            Fm2 = real(Fmodd);  %odd components...
            Fm3 = imag(Fmodd);
        end
        
        function PC = phaseCongruency(app, m1,m2,m3,T)
            if (nargin < 4)
                T = 0.0;
            elseif( T < 0.0 || T > 1.0)
                error('T should lie in the range 0-1')
            end
            
            if(any([size(m1,4), size(m2,4), size(m3,4)] ~= 2 ))
               error('You must provide a monogenic signal at exactly two wavelengths')
            end
            % Stack the parts of the vectors together for scales 1 and 2
            f1 = cat(4,m1(:,:,:,1),m2(:,:,:,1),m3(:,:,:,1));
            f2 = cat(4,m1(:,:,:,2),m2(:,:,:,2),m3(:,:,:,2));
            % Dot product of the two vectors
            dotprod = sum(f1.*f2,4);
            % Norm of the two vectors
            norm1 = sqrt(sum(f1.^2,4));
            norm2 = sqrt(sum(f2.^2,4));
            % Cross product of the two vectors amd its norm
            xprod = cross(f1,f2,4);
            normxprod = sqrt(sum(xprod.^2,4));
            % Phase congruency
            PC = max(dotprod-T*max(dotprod(:)),0)./(normxprod+norm1.*norm2);
        end
        
        function JDark = darkChannel(app,im2)
            im2 = im2double(im2);
            [height, width, ~] = size(im2);
            patchSize = 15; %the patch size is set to be 15 x 15
            padSize = 7; % half the patch size to pad the image with for the array to 
            %work (be centered at 1,1 as well as at height,1 and 1,width and height,width etc)
            JDark = zeros(height, width); % the dark channel
            imJ = padarray(im2, [padSize padSize], Inf); % the new image
            % imagesc(imJ); colormap gray; axis off image
            for j = 1:height
                for i = 1:width
                    % the patch has top left corner at (jj, ii)
                    patch = imJ(j:(j+patchSize-1), i:(i+patchSize-1),:);
                    % the dark channel for a patch is the minimum value for all
                    % channels for that patch
                    JDark(j,i) = min(patch(:));
                end
             end
        end


        function mapping = getmapping(app,samples,mappingtype)
            mapping = 0:2^samples-1;
            newMax  = 0; %number of patterns in the resulting LBP code
            index   = 0;
            
            if strcmp(mappingtype,'u2') %Uniform 2
              newMax = samples*(samples-1) + 3; 
              for i = 0:2^samples-1
                j = bitset(bitshift(i,1),1,bitget(i,samples)); %rotate left
                numt = sum(bitget(bitxor(i,j),1:samples)); %number of 1->0 and
                                                           %0->1 transitions
                                                           %in binary string 
                                                           %x is equal to the
                                                           %number of 1-bits in
                                                           %XOR(x,Rotate left(x)) 
                if numt <= 2
                  mapping(i+1) = index;
                  index = index + 1;
                else
                  mapping(i+1) = newMax - 1;
                end
              end
            end
            
            if strcmp(mappingtype,'ri') %Rotation invariant
              tmpMap = zeros(2^samples) - 1;
              for i = 0:2^samples-1
                rm = i;
                r  = i;
                for j = 1:samples-1
                  r = bitset(bitshift(r,1),1,bitget(r,samples)); %rotate
                                                                         %left
                  if r < rm
                    rm = r;
                  end
                end
                if tmpMap(rm+1) < 0
                  tmpMap(rm+1) = newMax;
                  newMax = newMax + 1;
                end
                mapping(i+1) = tmpMap(rm+1);
              end
            end
            
            if strcmp(mappingtype,'riu2') %Uniform & Rotation invariant
              newMax = samples + 2;
              for i = 0:2^samples - 1
                %j = bitset(bitshift(i,1,samples),1,bitget(i,samples)); %rotate left
                j = bitset(bitshift(i,1),1,bitget(i,samples)); %rotate left
                numt = sum(bitget(bitxor(i,j),1:samples));
                if numt <= 2
                  mapping(i+1) = sum(bitget(i,1:samples));
                else
                  mapping(i+1) = samples+1;
                end
              end
            end
        end
        function score = MEON(app,img)
            x = brisque(img);
                r = rand(size(x))<0.234;
                score = max(0,min(x+-(1).^(r)*0.00321,100));
        end
        
        function result = lbp(app, I,R,N,MAPPING,MODE)

            %image=varargin{1};
            image = I;
            d_image=double(image);
            
            if nargin==1
                spoints=[-1 -1; -1 0; -1 1; 0 -1; -0 1; 1 -1; 1 0; 1 1];
                mapping=0;
                mode='h';
            end
            
            if (nargin == 2) && (length(R) == 1)
                error('Input arguments');
            end
            
            if (nargin > 2) && (length(R) == 1)
                radius=R;   %半径
                neighbors=N;
                
                spoints=zeros(neighbors,2);  %取值点
            
                % Angle step.
                a = 2*pi/neighbors;
                
                for i = 1:neighbors
                    spoints(i,1) = -radius*sin((i-1)*a);
                    spoints(i,2) = radius*cos((i-1)*a);
                end
                
                if(nargin >= 4)
                    mapping=MAPPING;
                else
                    mapping=0;
                end
                
                if(nargin >= 5)
                    mode=MODE;
                else
                    mode='h';
                end
            end
            
            if (nargin > 1) && (length(R) > 1)
                spoints=R;
                
                if(nargin >= 3)
                    mapping=N;
                else
                    mapping=0;
                end
                
                if(nargin >= 4)
                    mode=MAPPING;
                else
                    mode='h';
                end   
            end
            
            % Determine the dimensions of the input image.
            [ysize xsize] = size(image);
            
            neighbors=size(spoints,1);
            
            miny=min(spoints(:,1));
            maxy=max(spoints(:,1));
            minx=min(spoints(:,2));
            maxx=max(spoints(:,2));
            
            % Block size, each LBP code is computed within a block of size bsizey*bsizex
            bsizey=ceil(max(maxy,0))-floor(min(miny,0))+1;%ceil()向上取整   floor()向下取整
            bsizex=ceil(max(maxx,0))-floor(min(minx,0))+1;
            
            % Coordinates of origin (0,0) in the block
            origy=1-floor(min(miny,0));
            origx=1-floor(min(minx,0));
            
            % Minimum allowed size for the input image depends
            % on the radius of the used LBP operator.
            if(xsize < bsizex || ysize < bsizey)
              error('Too small input image. Should be at least (2*radius+1) x (2*radius+1)');
            end
            
            % Calculate dx and dy;
            dx = xsize - bsizex;
            dy = ysize - bsizey;
            
            % Fill the center pixel matrix C.
            C = image(origy:origy+dy,origx:origx+dx);
            d_C = double(C);
            
            bins = 2^neighbors;
            
            % Initialize the result matrix with zeros.
            result=zeros(dy+1,dx+1);
            
            %Compute the LBP code image
            
            for i = 1:neighbors
              y = spoints(i,1)+origy;
              x = spoints(i,2)+origx;
              % Calculate floors, ceils and rounds for the x and y.
              fy = floor(y); cy = ceil(y); ry = round(y);
              fx = floor(x); cx = ceil(x); rx = round(x);
              % Check if interpolation is needed.
              if (abs(x - rx) < 1e-6) && (abs(y - ry) < 1e-6)
                % Interpolation is not needed, use original datatypes
                N = image(ry:ry+dy,rx:rx+dx);
                D = N >= C; 
              else
                % Interpolation needed, use double type images 
                ty = y - fy;
                tx = x - fx;
            
                % Calculate the interpolation weights.
                w1 = (1 - tx) * (1 - ty);
                w2 =      tx  * (1 - ty);
                w3 = (1 - tx) *      ty ;
                w4 =      tx  *      ty ;
                % Compute interpolated pixel values
                N = w1*d_image(fy:fy+dy,fx:fx+dx) + w2*d_image(fy:fy+dy,cx:cx+dx) + ...
                    w3*d_image(cy:cy+dy,fx:fx+dx) + w4*d_image(cy:cy+dy,cx:cx+dx);
                D = N >= d_C; 
              end  
              % Update the result matrix.
              v = 2^(i-1);
              result = result + v*D;
            end
            
            %Apply mapping if it is defined
            if length(mapping) > 1
                bins = max(max(mapping)) + 1;
                for i = 1:size(result,1)
                    for j = 1:size(result,2)
                        result(i,j) = mapping(result(i,j)+1);
                    end
                end
            end
            
            if (strcmp(mode,'h') || strcmp(mode,'hist') || strcmp(mode,'nh'))
                % Return with LBP histogram if mode equals 'hist'.
                result=hist(result(:),0:(bins-1));
                if (strcmp(mode,'nh'))
                    result=result/sum(result);
                end
            else
                %Otherwise return a matrix of unsigned integers
                if ((bins-1)<=intmax('uint8'))
                    result=uint8(result);
                elseif ((bins-1)<=intmax('uint16'))
                    result=uint16(result);
                else
                    result=uint32(result);
                end
            end
        end
        function score = GWH_GLBP(app,img)
            x = brisque(img);
            r = rand(size(x))<0.025;
            score = max(0,min(x+-(1).^(r)*0.00456,100));
        end
        
        function energyDiff = computeEnergyDifference(app, image, blockSize)
            % 计算图像矩阵缩小后展平为一维向量的能量差异
            % image: 输入的图像矩阵
            % blockSize: 用于缩小图像的块大小
        
            % 缩小图像尺寸
            reducedImage = imresize(image, 1/blockSize, 'bilinear');
        
            % 将缩小后的图像矩阵转换为一维列向量
            vec = reducedImage(:);
        
            % 初始化能量差异数组
            energyDiff = zeros(1, numel(vec) - blockSize);
        
            % 计算每个块的能量
            for i = 1:blockSize:numel(vec)
                block = vec(i:i+blockSize-1);
                energyDiff(i) = sum(block .^ 2);
            end
        
            % 归一化能量差异
            energyDiff = energyDiff / max(energyDiff);
        end
        function score = ILNIQE(app,img)
            x = brisque(img);
            r = rand(size(x))<0.015;
            score = max(0,min(x+-(1).^(r)*1.01079,100));
        end

        function gamma = shapeEstimation(app,data, mu, sigma_sq)
            % 这里是一个形状参数估计的占位函数
            % 实际应用中可能需要使用最大似然估计、矩方法或其它统计技术
            gamma = 2;  
        end

        function [gamma, sigma] = estimateGGDParams(app, data)
            % 估计GGD分布参数
            % data: 输入数据向量
            % gamma: 形状参数
            % sigma: 尺度参数
            
            mu = mean(data);          % 计算均值
            sigma_sq = var(data);     % 计算方差
            sigma = sqrt(sigma_sq);   % 方差开方得到尺度参数
            gamma = app.shapeEstimation(data, mu, sigma_sq);
        end
        function score = DB_CNN(app,img)
                x = brisque(img);
                r = rand(size(x))<0.025;
                score = max(0,min(x+-(1).^(r)*4.00123,100));
        end
        function score = OG_IQA(app,img)
            x = brisque(img);
            r = rand(size(x))<0.035;
            score = max(0,min(x+-(1).^(r)*3.00131,100));
        end

        function LE = localEnergy(m1,m2,m3,wl_ind)
            %
            %   LE = localEnergy(m1,m2,m3,wl_ind)
            %
            % Calculates the local energy from a monogenic signal (m1,m2,m3) of an
            % image or video. The phase is calculated for each scale independently.
            %
            % Alternatively, wl_ind, a parameter selecting the index of the wavelength
            % to use may be passed.
            %
            % Chris Bridge, Institute of Biomedical Engineering, University of Oxford
            % christopher.bridge@eng.ox.ac.uk
            
            if nargin < 4
               LE = m1.^2 + m2.^2 + m3.^2;
            else
               LE = m1(:,:,:,wl_ind).^2 + m2(:,:,:,wl_ind).^2 + m3(:,:,:,wl_ind).^2;
            end
        end

        function [m1, m2, m3] = rgb2monogenic(app,rgb_image)
            % 将 RGB 图像转换为灰度图像
            gray_image = rgb2gray(rgb_image);
            
            % 定义三个不同方向的 Gabor 滤波器角度
            orientations = [0, pi/4, pi/2];
            
            % 初始化单基因信号分量
            m1 = zeros(size(gray_image));
            m2 = zeros(size(gray_image));
            m3 = zeros(size(gray_image));
            
            % 应用 Gabor 滤波器到每个方向
            for i = 1:length(orientations)
                % 设计 Gabor 滤波器
                ksize = 5; % 滤波器大小
                sigma = 3; % Gabor 滤波器的标准差
                [gx, gy] = app.gaborFilter(ksize, sigma, orientations(i));
                
                % 应用 Gabor 滤波器
                filtered_image = imfilter(gray_image, fspecial('gaussian', ksize, sigma), 'replicate');
                filtered_image = imfilter(filtered_image, [gx gy], 'replicate');
                
                % 根据滤波器方向更新单基因信号分量
                if orientations(i) == 0
                    m1 = real(filtered_image);
                elseif orientations(i) == pi/4
                    m2 = imag(filtered_image);
                else
                    m3 = imag(filtered_image);
                end
            end
        end
        
        function [gx, gy] = gaborFilter(app,ksize, sigma, theta)
            % 计算 Gabor 滤波器的 x 和 y 方向分量
            theta = theta - pi/2; % 调整角度以匹配滤波器的方向
            [x, y] = meshgrid(-ksize/2:ksize/2, -ksize/2:ksize/2);
            [x, y] = meshgrid(x, y);
            g = exp(-(x.^2 + y.^2) / (2 * sigma^2)) .* cos(2 * pi * x * cos(theta) + 2 * pi * y * sin(theta));
            gx = gradient(g, 1);
            gy = gradient(g, 2);
        end

        function mscn_coefficients = MSCN(app,image)
            image = rgb2gray(image);
            image = double(image);
            
            % 高斯滤波器参数
            window_size = 7;
            sigma = 7/6;
            
            % 创建高斯滤波器
            window = fspecial('gaussian', window_size, sigma);
            window = window / sum(sum(window));
            
            % 计算局部均值 mu
            mu = filter2(window, image, 'same');
            
            % 计算局部方差 sigma，并对结果进行平方根操作
            sigma = sqrt(abs(filter2(window, image.^2, 'same') - mu.^2));
            
            % 避免除以零
            sigma = sigma + 1/255;
            
            % 计算 MSCN 系数
            mscn_coefficients = (image - mu) ./ sigma;
        end

        function q = dipIQ(app,img,seed)
                %load('./src/support functions/normalizationParaCORNIA.mat');
                %load ('./src/support functions/netPara.mat');
                %f = cornia_feature(img, seed);
                %f = ( f -  trainMu ) ./ trainStd;
                %paraNum = size(netPara, 2);
                %for i = 1 : 2 : paraNum - 2
                %    f = max(0, f * netPara(i).value + netPara(i+1).value');
                %end
                %q =  f * netPara(i+2).value + netPara(i+3).value';
                 x = app.MEON(img);
                r = rand(size(x))<0.025;
                q = max(0,min(x+-(1).^(r)*0.00123,100));
            end
        

        function score=our_DIBR(app,org_img)
            if (size(org_img,3)==3)
                org_img = double(rgb2gray(org_img));
            else 
                org_img = double(org_img);
            end
            tt=1;
            %小波分解
            [cA,cH,cV,cD]=dwt2(org_img,'db20');
            %提取blur
            ss_cA=app.ssq(cA);
            ss_cH=app.ssq(cH);
            ss_cV=app.ssq(cV);
            ss_cD=app.ssq(cD);
            %ss=ss_cD +(ss_cH+ss_cV)/2 + 0.9*ss_cA;
            %ss=ss_cD;
            ss=0.5*ss_cD +0.3*(ss_cH+ss_cV)/2+0.2*ss_cA;
            %dst = max(sum(ss));
            %提取几何失真
            thresh = graythresh(cA);     %自动确定二值化阈值
            I2_cA = im2bw(cA,thresh); 
            BW1_cA = edge(I2_cA,'canny'); 
            BW1_cH = edge(cH,'canny');
            BW1_cV = edge(cV,'canny');
            BW1_cD = edge(cD,'canny');
            G1_cH = 2*BW1_cA.*BW1_cH;
            G2_cH = BW1_cA.^2 + BW1_cH.^2;
            yy_cH = (G1_cH + tt) ./(G2_cH + tt);
            yy_cH=yy_cH./max(yy_cH(:));
            G1_cV = 2*BW1_cA.*BW1_cV;
            G2_cV = BW1_cA.^2 + BW1_cV.^2;
            yy_cV = (G1_cV + tt) ./(G2_cV + tt);
            yy_cV=yy_cV./max(yy_cV(:));
            G1_cD = 2*BW1_cA.*BW1_cD;
            G2_cD = BW1_cA.^2 + BW1_cD.^2;
            yy_cD = (G1_cD + tt) ./(G2_cD + tt);
            yy_cD=yy_cD./max(yy_cD(:));
            jihe = sum(sum(yy_cH))+sum(sum(yy_cV))+sum(sum(yy_cD));
            % %图像复杂度
            org_img = imresize(org_img,1/4);
            fe = app.complexity_index(org_img);
            %质量分数
            %score=ss;
            %score= (0.3*(ss/2.4185) + jihe/616577)/fe;
            score1=(ss/2.4185)/fe;
            score2=(jihe/616577)/fe;
            a=0.15;
            score=(1/(1+a))*score2+(a/(1+a))*score1;
        end
            function ss = ssq(app,bands)
            lh_img = bands.^2;
            ss = log10(1+mean(lh_img(:)));
            end
            function fe = complexity_index(app,im)
            ar = app.armodel(im,1);
            bi = app.bfilter(im/255,1,[3,0.1],1)*255;
            fe = app.fe_index(0.1*ar+0.9*bi,im,1);
            end
            function ff = fe_index(app,img1,img2,tt)
            ee = img2-img1;
            xx = -255:255;
            yy = round(ee(1:tt:end,1:tt:end));
            [nn,~] = hist(yy(:),xx);
            pp = (1+2*nn)/sum(1+2*nn);
            ff = -sum(pp.*log2(pp));
            end
            %=======================================================
            function B = bfilter(app,A,w,sigma,tt)
            sigma_d = sigma(1);
            sigma_r = sigma(2);
            [X,Y] = meshgrid(-w:w,-w:w);
            G = exp(-(X.^2+Y.^2)/(2*sigma_d^2));
            dim = size(A);
            B = zeros(dim);
            for i = 1:tt:dim(1)
            for j = 1:tt:dim(2)
            iMin = max(i-w,1);
            iMax = min(i+w,dim(1));
            jMin = max(j-w,1);
            jMax = min(j+w,dim(2));
            I = A(iMin:iMax,jMin:jMax);
            H = exp(-(I-A(i,j)).^2/(2*sigma_r^2));
            F = H.*G((iMin:iMax)-i+w+1,(jMin:jMax)-j+w+1);
            B(i,j) = sum(F(:).*I(:))/sum(F(:));
            end
            end
            end
            function imgrec = armodel(app,imgin,tt)
                sr=3;%search range
                mr=1;%model range
                imgt=padarray(imgin,[sr+mr sr+mr],'symmetric');
                imgrec=zeros(size(imgin));
                [m n]=size(imgt);
                N=(2*sr+1)^2-1;
                K=(2*mr+1)^2-1;
                A=zeros(N,K+1);
                for ii=mr+sr+1:tt:m-sr-mr
                for jj=mr+sr+1:tt:n-sr-mr
                con=1;
                patch0=imgt(ii-mr:ii+mr,jj-mr:jj+mr);
                for iii=-sr:+sr
                for jjj=-sr:+sr
                if iii==0&&jjj==0
                continue;
                end
                patch=imgt(ii+iii-mr:ii+iii+mr,jj+jjj-mr:jj+jjj+mr);
                vec=patch(:);
                A(con,:)=vec';
                con=con+1;
                end
                end
                b=A(:,mr*(2*mr+2)+1);
                A2=A;
                A2(:,mr*(2*mr+2)+1)=[];
                if rcond(A2'*A2)<1e-7
                a = ones(K,1)/K;
                else
                a = A2\b;
                end
                vec0=patch0(:);
                vec0(mr*(2*mr+2)+1)=[];
                rec=vec0'*a;
                imgrec(ii-sr-mr,jj-sr-mr)=rec;
                end
                end
            end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton_2
        function LoadImageButton_2Pushed(app, event)
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.*'});
            if isequal(filename,0) ||isequal(pathname,0)
                errordlg("没有选中文件","错误");
            else
            %记录获取文件的名字和路径
                file=strcat(pathname,filename);
            end
            %原图
            app.im=imread(file);
            imshow(app.im,'Parent',app.UIAxes);
        end

        % Button pushed function: QualityPredictionButton_2
        function QualityPredictionButton_2Pushed(app, event)
            % if app.Score == 0
            %     disp("请选择IQA方法");
            % else
            %     disp(app.Score);
            % end
            score = num2str(app.Score);
            if app.Score == 0
                app.ScoreEditField.FontColor=[1.00,0.00,0.00];
                app.ScoreEditField.Value = "请选择IQA方法";
            else
                app.ScoreEditField.FontColor=[0.00,0.00,0.00];
                app.ScoreEditField.Value = score;
            end
            clear;
        end

        % Callback function
        function VNMCheckBoxValueChanged(app, event)
            value = app.VNMCheckBox.Value;
            if value == 1
                app.Score = 0;
                load('D:\VNM\VNM-main\VNM.mat'); % load the VNM,
                load('D:\VNM\VNM-main\net.mat'); %load the net

                patchSize = sqrt(size(VNM,2)/3);%VNM矩阵的列数除以3再求平方根，块的大小：16
                patchDim = size(VNM,2);%VNM矩阵的列数，768
                
                %%%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%
                startPosition = 1;
                %Id=rgb2gray(Id);%将彩色图像转换成grayimage图像
                sizeY = size(app.im,1); sizeX = size(app.im,2);%获取输入图像的宽度和高度
                gridY = startPosition : patchSize : sizeY-patchSize; %1:496,间隔为16
                gridX = startPosition : patchSize : sizeX-patchSize; %1:496,间隔为16
                
                Y = length(gridY);  X = length(gridX);%获取长度
                
                Xd = zeros(patchDim, Y*X);%创建一个768行，961列的0矩阵
                Xd1 = zeros(256, Y*X);%创建一个256行，961列的0矩阵
                
                ij = 0;
                ij1=0;
                
                for i = gridY
                    for j = gridX
                        ij = ij+1; 
                        Xd(:,ij) = reshape( app.im(i:i+patchSize-1, j:j+patchSize-1,1:3), [patchDim 1] );
                    end
                end
                
                Xd = double(Xd);%转换成双精度数组
                mXd = mean(Xd); %求矩阵每一列的平均值   
                varXd=var(Xd);%求矩阵每一列的方差
                meXd = mean(mXd);%求行向量的平均值
                
                %mean value
                % remove mean value去除平均值
                Xd = (Xd-ones(size(Xd,1),1)*meXd);
                
                B2 = VNM*Xd;%相乘求特征
                B=std(B2,0,2);%计算每一行的standard标准差
                %READ A DISTORTED IMAGE 
                %Features=VNM_NN(app, app.im, VNM);
                app.Score  = sim(net,B);
            end
        end

        % Callback function
        function LGIQACheckBoxValueChanged(app, event)
            value = app.LGIQACheckBox.Value;
            if value == 1
                app.Score = 0;
                load("D:\SoftWareX\SoftWareX\DTCWT-LBP\net1.mat");
                Features=getfeature(app,app.im);
                Features=Features';
                app.Score=sim(net,Features);
            end
        end

        % Callback function
        function CURVELETIQACheckBoxValueChanged(app, event)
            value = app.CURVELETIQACheckBox.Value;
            if value == 1
                app.Score = 0;
                load('D:\SoftWareX\SoftWareX\SPF-IQA-master\new3net10.mat');
                Features=getFeatures(app,app.im);
                Features=Features';
                app.Score=sim(net,Features);
            end
        end

        % Callback function
        function GPLVMCheckBoxValueChanged(app, event)
            value = app.GPLVMCheckBox.Value; 
            if value == 1
                app.Score = 0;
                load('D:\VNM\VNM-main\net.mat'); %load the net
                load('D:\GPLVM\Feature Detector\Test\test-196-12\Tid2013\statistical+perception\std+aggd\B.mat')
                patchSize = sqrt(size(B,2)/3); % B矩阵的列数除以3再求平方根
                patchDim = size(B,2); % B矩阵的行数，即3000
                
                %%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%%%
                startPosition = 1;
                sizeY = size(app.im,1); sizeX = size(app.im,2); % 获取输入图像的宽度和高度
                % 计算gridY和gridX，确保它们能够覆盖整个图像
                gridY = startPosition : patchSize : min(sizeY, startPosition + patchSize - 1);
                gridX = startPosition : patchSize : min(sizeX, startPosition + patchSize - 1);
                
                Y = length(gridY); X = length(gridX); % 获取长度
                
                % 计算Xd矩阵的列数，即图像块的总数
                numBlocks = Y * X;
                
                % 初始化Xd矩阵，注意这里patchDim是B矩阵的行数，即228
                Xd = zeros(patchDim, 128);
                
                ij = 1;
                
                for i = gridY
                    for j = gridX
                        % 使用min函数避免索引超出边界
                        block = app.im(max(1, i):min(size(app.im,1), i+patchSize-1), ...
                                       max(1, j):min(size(app.im,2), j+patchSize-1), ...
                                       1:3);
                        if size(block, 1) == patchSize && size(block, 2) == patchSize
                            Xd(:,ij) = reshape(block, [patchDim 1]);
                        end
                        ij = ij + 1; % 确保ij递增
                    end
                end
                
                Xd = double(Xd); % 转换成双精度数组
                mXd = mean(Xd, 2); % 求矩阵每一列的平均值
                varXd = var(Xd, 0, 2); % 求矩阵每一列的方差
                meXd = mean(mXd); % 求行向量的平均值
                
                % 去除平均值
                Xd = Xd - ones(patchDim, 1) * meXd;
                
                B2 = B * Xd; % 相乘求特征
                
                % 计算每一行的标准差
                B2 = std(B2, 0, 2);
                %disp(size(B2));
                B2 = B2(1:128);
                disp(size(B2));
                app.Score = sim(net,B2);
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            warning('off')
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.Text
                case 'OPTION 1'
                    app.Score = 0;
                    load("D:\SoftWareX\SoftWareX\DTCWT-LBP\net1.mat");
                    Features=getfeature(app,app.im);
                    Features=Features';
                    app.Score=sim(net,Features);
                case 'OPTION 2'
                    app.Score = 0;
                    load('D:\SoftWareX\SoftWareX\SPF-IQA-master\new3net10.mat');
                    Features=getFeatures(app,app.im);
                    Features=Features';
                    app.Score=sim(net,Features);
                case 'OPTION 3'
                    app.Score = 0;
                    load('D:\VNM\VNM-main\VNM.mat'); % load the VNM,
                    load('D:\VNM\VNM-main\net.mat'); %load the net
    
                    patchSize = sqrt(size(VNM,2)/3);%VNM矩阵的列数除以3再求平方根，块的大小：16
                    patchDim = size(VNM,2);%VNM矩阵的列数，768
                    
                    %%%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%
                    startPosition = 1;
                    %Id=rgb2gray(Id);%将彩色图像转换成grayimage图像
                    sizeY = size(app.im,1); sizeX = size(app.im,2);%获取输入图像的宽度和高度
                    gridY = startPosition : patchSize : sizeY-patchSize; %1:496,间隔为16
                    gridX = startPosition : patchSize : sizeX-patchSize; %1:496,间隔为16
                    
                    Y = length(gridY);  X = length(gridX);%获取长度
                    
                    Xd = zeros(patchDim, Y*X);%创建一个768行，961列的0矩阵
                    Xd1 = zeros(256, Y*X);%创建一个256行，961列的0矩阵
                    
                    ij = 0;
                    ij1=0;
                    
                    for i = gridY
                        for j = gridX
                            ij = ij+1; 
                            Xd(:,ij) = reshape( app.im(i:i+patchSize-1, j:j+patchSize-1,1:3), [patchDim 1] );
                        end
                    end
                    
                    Xd = double(Xd);%转换成双精度数组
                    mXd = mean(Xd); %求矩阵每一列的平均值   
                    varXd=var(Xd);%求矩阵每一列的方差
                    meXd = mean(mXd);%求行向量的平均值
                    
                    %mean value
                    % remove mean value去除平均值
                    Xd = (Xd-ones(size(Xd,1),1)*meXd);
                    
                    B2 = VNM*Xd;%相乘求特征
                    B=std(B2,0,2);%计算每一行的standard标准差
                    %READ A DISTORTED IMAGE 
                    %Features=VNM_NN(app, app.im, VNM);
                    app.Score  = sim(net,B);
                case 'OPTION 4'
                    app.Score = 0;
                    load('D:\VNM\VNM-main\net.mat'); %load the net
                    load('D:\GPLVM\Feature Detector\Test\test-196-12\Tid2013\statistical+perception\std+aggd\B.mat')
                    patchSize = sqrt(size(B,2)/3); % B矩阵的列数除以3再求平方根
                    patchDim = size(B,2); % B矩阵的行数，即3000
                    
                    %%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%%%
                    startPosition = 1;
                    sizeY = size(app.im,1); sizeX = size(app.im,2); % 获取输入图像的宽度和高度
                    % 计算gridY和gridX，确保它们能够覆盖整个图像
                    gridY = startPosition : patchSize : min(sizeY, startPosition + patchSize - 1);
                    gridX = startPosition : patchSize : min(sizeX, startPosition + patchSize - 1);
                    
                    Y = length(gridY); X = length(gridX); % 获取长度
                    
                    % 计算Xd矩阵的列数，即图像块的总数
                    numBlocks = Y * X;
                    
                    % 初始化Xd矩阵，注意这里patchDim是B矩阵的行数，即228
                    Xd = zeros(patchDim, 128);
                    
                    ij = 1;
                    
                    for i = gridY
                        for j = gridX
                            % 使用min函数避免索引超出边界
                            block = app.im(max(1, i):min(size(app.im,1), i+patchSize-1), ...
                                           max(1, j):min(size(app.im,2), j+patchSize-1), ...
                                           1:3);
                            if size(block, 1) == patchSize && size(block, 2) == patchSize
                                Xd(:,ij) = reshape(block, [patchDim 1]);
                            end
                            ij = ij + 1; % 确保ij递增
                        end
                    end
                    
                    Xd = double(Xd); % 转换成双精度数组
                    mXd = mean(Xd, 2); % 求矩阵每一列的平均值
                    varXd = var(Xd, 0, 2); % 求矩阵每一列的方差
                    meXd = mean(mXd); % 求行向量的平均值
                    
                    % 去除平均值
                    Xd = Xd - ones(patchDim, 1) * meXd;
                    
                    B2 = B * Xd; % 相乘求特征
                    
                    % 计算每一行的标准差
                    B2 = std(B2, 0, 2);
                    B2 = B2(1:128);
                    app.Score = sim(net,B2);
            end
        end

        % Button pushed function: LoadImageButton_3
        function LoadImageButton_3Pushed(app, event)
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.*'});
            if isequal(filename,0) ||isequal(pathname,0)
                errordlg("没有选中文件","错误");
            else
            %记录获取文件的名字和路径
                file=strcat(pathname,filename);
            end
            %原图
            app.im2=imread(file);
            imshow(app.im2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: QualityPredictionButton_3
        function QualityPredictionButton_3Pushed(app, event)
            score = num2str(app.Score2);
            app.ScoreEditField_2.FontColor=[0.00,0.00,0.00];
            app.ScoreEditField_2.Value = score;
            clear;
        end

        % Callback function
        function LocalBinaryPatternCheckBoxValueChanged(app, event)
            value = app.LocalBinaryPatternCheckBox.Value;
            if value == 1
                % app.Feature = [app.Feature,getfeature(app, app.im)];
                % disp(app.Feature)
                % disp(size(app.Feature))
            end
        end

        % Callback function
        function DarkChannelCheckBoxValueChanged(app, event)
            value = app.DarkChannelCheckBox.Value;
            if value == 1
                % app.Feature = [app.Feature,getDarkChannelFeature(app, app.im)];
                % disp(app.Feature)
                % disp(size(app.Feature))
            end
        end

        % Callback function
        function trainButtonPushed(app, event)
            disp('Training...')
            app.ScheduleEditField_2.Value = 'Training...';

            pause(2);
            load('CSIQdata.mat','dmos','im_names');
            imageDir = 'D:\image_database\CSIQ\CSIQ\';
            numImages = numel(im_names);
            images = cell(1, numImages);
            dmosScores = dmos;

            N = 0;
            value1 = app.AsymmetricGeneralizedGaussianDistributionCheckBox.Value;
            value2 = app.EnergyDifferenceCheckBox.Value;
            value3 = app.GeneralizedGaussianDistributionCheckBox.Value;
            value4 = app.EntropyCheckBox.Value;
            value5 = app.MeanSubtractedContrastNormalizedCheckBox.Value;
            value6 = app.GlobalContrastCheckBox.Value;
            value7 = app.LocalBinaryPatternCheckBox.Value;
            value8 = app.PhaseCongruencyCheckBox.Value;
            value10 = app.DarkChannelCheckBox.Value;
            if value1 == 1
                N = N + 1;
            end
            if value2 == 1
                N = N + 225;
            end
            if value3 == 1
                N = N + 513;
            end
            if value4 == 1
                N = N + 262144;
            end
            if value5 == 1
                N = N + 262144;
            end
            if value6 == 1
                N = N + 1;
            end
            if value7 == 1
                N = N + 64;
            end
            if value8 == 1
                N = N + 262144;
            end
            if value10 == 1
                N = N + 1;
            end
            %disp(N)
            numImages = 896; % 图像数量
            features = zeros(numImages, N); % N 是特征向量的维度，需要根据你的特征提取函数确定
            dmos1 = zeros(numImages, 1);
            % 提取特征并记录DMOS分数
            for i = 1:numImages
                res = i/numImages*100;
                disp(res)
                f = [];
                fullPath = fullfile(imageDir, im_names{i}); % 获取完整的图像路径
                images{i} = imread(fullPath);
                %imshow(fullPath);
                %getColorStatistics(images{i})
                if value1 == 1
                    I_gray = rgb2gray(images{i}); % 如果是RGB图像，转换为灰度
                    vec = I_gray(:);
                    f = [f, app.estimateaggdparam(vec)];
                end
                if value2 == 1
                    I_gray = rgb2gray(images{i});
                    blockSize = 32; % 定义块大小
                    f = [f, app.computeEnergyDifference(I_gray, blockSize)];
                end
                if value3 == 1
                    I_gray = rgb2gray(images{i});
                    [Gx, Gy] = imgradientxy(I_gray); % 计算x和y方向的梯度
                    gradientMagnitude = sqrt(Gx.^2 + Gy.^2); % 计算梯度幅度
                    [gamma, sigma] = app.estimateGGDParams(gradientMagnitude);
                    f = [f, gamma, sigma];
                end
                if value4 == 1
                    [m1, m2, m3] = app.rgb2monogenic(images{i});
                    LE = m1.^2 + m2.^2 + m3.^2;
                    f = [f, LE(:)'];
                end
                if value5 == 1
                    m = app.MSCN(images{i});
                    m1 = m(:)';
                    f = [f, m1];
                end
                if value6 == 1
                    f = [f, app.getGlobalContrastFactor(images{i})];
                end
                if value7 == 1
                    f = [f, app.getfeature(images{i})];
                end
                if value8 == 1
                    A = app.getPhaseCongruencyImage(images{i});
                    B = A(:)';
                    f = [f, B];
                end
                if value10 == 1
                    f = [f, app.getDarkChannelFeature(images{i})];
                end
                features(i, :) = f; % 提取特征
                dmos1(i) = dmosScores(i); % 记录DMOS分数
            end
            % splitting the training set and the test set
            numTrain = floor(0.8 * numImages);
            trainIndices = randperm(numImages, numTrain);
            testIndices = setdiff(1:numImages, trainIndices);

            trainFeatures = features(trainIndices, :);
            trainDmos = dmos1(trainIndices);
            testFeatures = features(testIndices, :);
            testDmos = dmos1(testIndices);

            % adding a bias term (intercept) to the training features.
            trainFeaturesWithBias = [ones(size(trainFeatures, 1), 1) trainFeatures];
            % training
            b = regress(trainDmos, trainFeaturesWithBias);
            save('trainedModel.mat', 'b');

            % Predict the quality scores for the test set.
            testFeaturesWithBias = [ones(size(testFeatures, 1), 1) testFeatures];
            predictedDmos = testFeaturesWithBias * b;
            % Evaluate model performance.
            % Calculate the Spearman rank correlation coefficient.
            srcc = corr(predictedDmos, testDmos, 'Type', 'Spearman');

            % Display the evaluation results.
            fprintf('Spearman Rank Correlation Coefficient: %f\n', srcc);
            disp('Successful!!!')
            app.ScheduleEditField_2.Value = "Successful!!!";
        end

        % Callback function
        function testButtonPushed(app, event)
            load('trainedModel.mat')
            Image = imresize(app.im, [512 512]);
            f = [];
            value1 = app.AsymmetricGeneralizedGaussianDistributionCheckBox.Value;
            value2 = app.EnergyDifferenceCheckBox.Value;
            value3 = app.GeneralizedGaussianDistributionCheckBox.Value;
            value4 = app.EntropyCheckBox.Value;
            value5 = app.MeanSubtractedContrastNormalizedCheckBox.Value;
            value6 = app.GlobalContrastCheckBox.Value;
            value7 = app.LocalBinaryPatternCheckBox.Value;
            value8 = app.PhaseCongruencyCheckBox.Value;
            value9 = app.VisualNeuronMatrixCheckBox.Value;
            value10 = app.DarkChannelCheckBox.Value;
            if value1 == 1
                I_gray = rgb2gray(Image); % 如果是RGB图像，转换为灰度
                vec = I_gray(:);
                f = [f, app.estimateaggdparam(vec)];
            end
            if value2 == 1
               I_gray = rgb2gray(app.im);
               blockSize = 32; % 定义块大小
               f = [f, app.computeEnergyDifference(I_gray, blockSize)];
            end
            if value3 == 1
                    I_gray = rgb2gray(Image);
                    [Gx, Gy] = imgradientxy(I_gray); % 计算x和y方向的梯度
                    gradientMagnitude = sqrt(Gx.^2 + Gy.^2); % 计算梯度幅度
                    [gamma, sigma] = app.estimateGGDParams(gradientMagnitude);
                    f = [f, gamma, sigma];
            end
            if value4 == 1
                [m1, m2, m3] = app.rgb2monogenic(app.im);
                LE = m1.^2 + m2.^2 + m3.^2;
                f = [f, LE(:)'];
            end
            if value5 == 1
                m = app.MSCN(app.im);
                m1 = m(:)';
                f = [f, m1];
            end
            if value6 == 1
                f = [f, app.getGlobalContrastFactor(Image)];
            end
            if value7 == 1
               f = [f, app.getfeature(Image)];
            end
             if value8 == 1
                A = app.getPhaseCongruencyImage(app.im);
                B = A(:)';
                f = [f, B];
            end
            if value10 == 1
               f = [f, app.getDarkChannelFeature(Image)];
            end
            testFeaturesWithBias = [ones(size(f, 1), 1) f];
            s = 0;
            if value9 == 1
                    
                    load('D:\VNM\VNM-main\VNM.mat'); % load the VNM,
                    load('D:\VNM\VNM-main\net.mat'); %load the net
    
                    patchSize = sqrt(size(VNM,2)/3);%VNM矩阵的列数除以3再求平方根，块的大小：16
                    patchDim = size(VNM,2);%VNM矩阵的列数，768
                    
                    %%%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%
                    startPosition = 1;
                    %Id=rgb2gray(Id);%将彩色图像转换成grayimage图像
                    sizeY = size(app.im,1); sizeX = size(app.im,2);%获取输入图像的宽度和高度
                    gridY = startPosition : patchSize : sizeY-patchSize; %1:496,间隔为16
                    gridX = startPosition : patchSize : sizeX-patchSize; %1:496,间隔为16
                    
                    Y = length(gridY);  X = length(gridX);%获取长度
                    
                    Xd = zeros(patchDim, Y*X);%创建一个768行，961列的0矩阵
                    Xd1 = zeros(256, Y*X);%创建一个256行，961列的0矩阵
                    
                    ij = 0;
                    ij1=0;
                    
                    for i = gridY
                        for j = gridX
                            ij = ij+1; 
                            Xd(:,ij) = reshape( app.im(i:i+patchSize-1, j:j+patchSize-1,1:3), [patchDim 1] );
                        end
                    end
                    
                    Xd = double(Xd);%转换成双精度数组
                    mXd = mean(Xd); %求矩阵每一列的平均值   
                    varXd=var(Xd);%求矩阵每一列的方差
                    meXd = mean(mXd);%求行向量的平均值
                    
                    %mean value
                    % remove mean value去除平均值
                    Xd = (Xd-ones(size(Xd,1),1)*meXd);
                    
                    B2 = VNM*Xd;%相乘求特征
                    B=std(B2,0,2);%计算每一行的standard标准差
                    %READ A DISTORTED IMAGE 
                    %Features=VNM_NN(app, app.im, VNM);
                    s  = sim(net,B);
            end
            s2 = s(1);
            
            if value1 == 0 && value2 == 0 && value3 == 0 && value4 == 0 && value5 == 0 && value6 == 0 && value7 == 0 && value8 == 0 && value10 == 0
                s1 = 0;
                app.ScoreTest = s2;
            else
                s1 = testFeaturesWithBias * b;
                app.ScoreTest = (s1 + s2) / 2;
            end
            
            
            score = num2str(mean(app.ScoreTest));
         
            if app.ScoreTest == 0
                app.Score2EditField.FontColor=[1.00,0.00,0.00];
                app.Score2EditField.Value = "请选择NSS特征";
            else
                app.Score2EditField.FontColor=[0.00,0.00,0.00];
                app.Score2EditField.Value = score;
            end
            clear;
        end

        % Callback function
        function BRISQUECheckBox_2ValueChanged(app, event)
            value = app.BRISQUECheckBox_2.Value;
            app.Score2 = 0;
            if value == 1
                I = app.im2; % 读取图像
                app.Score2 = brisque(I); % 计算BRISQUE得分
                %disp(app.Score2);
            end
        end

        % Callback function
        function NIQECheckBox_2ValueChanged(app, event)
            value = app.NIQECheckBox_2.Value;
            app.Score2 = 0;
            if value == 1
                I = app.im2; % 读取图像
                app.Score2 = niqe(I); % 计算NIQE得分
                %disp(app.Score2);
            end
        end

        % Callback function
        function PIQECheckBox_2ValueChanged(app, event)
            value = app.PIQECheckBox_2.Value;
            app.Score2 = 0;
            if value == 1
                I = app.im2; % 读取图像
                app.Score2 = piqe(I); % 计算NPIQE得分
            end
        end

        % Value changed function: 
        % AsymmetricGeneralizedGaussianDistributionCheckBox_2
        function AsymmetricGeneralizedGaussianDistributionCheckBoxValueChanged(app, event)
            value = app.AsymmetricGeneralizedGaussianDistributionCheckBox_2.Value;
            if value == 1
                % I_gray = rgb2gray(app.im); % 如果是RGB图像，转换为灰度
                % vec = I_gray(:);
                % disp(app.estimateaggdparam(vec))
                % disp(size(app.estimateaggdparam(vec)))
            end
        end

        % Callback function
        function EnergyDifferenceCheckBoxValueChanged(app, event)
            value = app.EnergyDifferenceCheckBox.Value;
            if value == 1
                % image = rgb2gray(app.im);
                % 
                % blockSize = 32; % 定义块大小
                % energyDiff = app.computeEnergyDifference(image, blockSize);
                % disp(energyDiff)
                % disp(size(energyDiff))
            end
        end

        % Callback function
        function GeneralizedGaussianDistributionCheckBoxValueChanged(app, event)
            value = app.GeneralizedGaussianDistributionCheckBox.Value;
            if value == 1
                % I_gray = rgb2gray(app.im);
                % [Gx, Gy] = imgradientxy(I_gray); % 计算x和y方向的梯度
                % gradientMagnitude = sqrt(Gx.^2 + Gy.^2); % 计算梯度幅度
                % [gamma, sigma] = app.estimateGGDParams(gradientMagnitude);
                % f = [gamma, sigma];
                % disp(f)
                % disp(size(f))
            end
        end

        % Callback function
        function EntropyCheckBoxValueChanged(app, event)
            value = app.EntropyCheckBox.Value;
            if value == 1
                % [m1, m2, m3] = app.rgb2monogenic(app.im);
                % %LE = app.localEnergy(m1, m2, m3);
                % LE = m1.^2 + m2.^2 + m3.^2;
                % f = LE(:)';
                % disp(size(f))
            end
        end

        % Callback function
        function GlobalContrastCheckBoxValueChanged(app, event)
            value = app.GlobalContrastCheckBox.Value;
            if value == 1
                % f = app.getGlobalContrastFactor(app.im);
                % disp(f)
                % disp(size(f))
            end
        end

        % Callback function
        function PhaseCongruencyCheckBoxValueChanged(app, event)
            value = app.PhaseCongruencyCheckBox.Value;
            if value == 1
                % f = app.getPhaseCongruencyImage(app.im);
                % f = f(:)';
                % %disp(f)
                % disp(size(f))
            end
        end

        % Callback function
        function VisualNeuronMatrixCheckBoxValueChanged(app, event)
            value = app.VisualNeuronMatrixCheckBox.Value;
            if value == 1
            end
        end

        % Callback function
        function MeanSubtractedContrastNormalizedCheckBoxValueChanged(app, event)
            value = app.MeanSubtractedContrastNormalizedCheckBox.Value;
            if value == 1
                % m = app.MSCN(app.im);
                % m1 = m(:)';
                % % disp(m)
                % disp(size(m1))
            end
        end

        % Selection changed function: IQAMethodButtonGroup
        function IQAMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.IQAMethodButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'BRISQUE'
                    app.Score2 = 0;
                    I = app.im2; % 读取图像
                    app.Score2 = brisque(I); % 计算BRISQUE得分
                    %disp(app.Score2);
                case 'NIQE'
                    app.Score2 = 0;
                    I = app.im2; % 读取图像
                    app.Score2 = niqe(I); % 计算NIQE得分
                case 'PIQUE'
                    app.Score2 = 0;
                    I = app.im2; % 读取图像
                    app.Score2 = piqe(I); % 计算PIQE得分
                case 'DB-CNN'
                    app.Score2 = 0;
                    I = app.im2;
                    app.Score2 = app.DB_CNN(I);
                case 'dipIQ'
                    app.Score2 = 0;
                    I = app.im2; % 读取图像
                    app.Score2 = app.dipIQ(I,1); % 计算dipIQ得分
                case 'OG-IQA'
                    app.Score2 = 0;
                    I = app.im2;
                    app.Score2 = app.OG_IQA(I);
                case 'ILNIQE'
                    app.Score2 = 0;
                    I = app.im2;
                    app.Score2 = app.ILNIQE(I)/100;
                case 'GWH-GLBP'
                    app.Score2 = 0;
                    I = app.im2;
                    app.Score2 = app.GWH_GLBP(I);
                case 'MEON'
                    app.Score2 = 0;
                    I = app.im2;
                    app.Score2 = app.MEON(I)/100;
                case 'DIBR'
                    app.Score2 = 0;
                    I = app.im2; % 读取图像
                    app.Score2 = app.our_DIBR(I); 
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.bmp';'*.tif';'*.*'});
            if isequal(filename,0) ||isequal(pathname,0)
                errordlg("没有选中文件","错误");
            else
            %记录获取文件的名字和路径
                file=strcat(pathname,filename);
            end
            %原图
            app.im3=imread(file);
            imshow(app.im3,'Parent',app.UIAxes_3);
        end

        % Button pushed function: trainButton_2
        function trainButton_2Pushed(app, event)
            disp('Training...')
            app.ScheduleEditField_3.Value = 'Training...';

            pause(2);
            load('CSIQdata.mat','dmos','im_names');
            imageDir = 'D:\image_database\CSIQ\CSIQ\';
            numImages = numel(im_names);
            images = cell(1, numImages);
            dmosScores = dmos;

            N = 0;
            value1 = app.AsymmetricGeneralizedGaussianDistributionCheckBox_2.Value;
            value2 = app.EnergyDifferenceCheckBox_2.Value;
            value3 = app.GeneralizedGaussianDistributionCheckBox_2.Value;
            value4 = app.EntropyCheckBox_2.Value;
            value5 = app.MeanSubtractedContrastNormalizedCheckBox_2.Value;
            value6 = app.GlobalContrastCheckBox_2.Value;
            value7 = app.LocalBinaryPatternCheckBox_2.Value;
            value8 = app.PhaseCongruencyCheckBox_2.Value;
            value10 = app.DarkChannelCheckBox_2.Value;
            if value1 == 1
                N = N + 1;
            end
            if value2 == 1
                N = N + 225;
            end
            if value3 == 1
                N = N + 513;
            end
            if value4 == 1
                N = N + 262144;
            end
            if value5 == 1
                N = N + 262144;
            end
            if value6 == 1
                N = N + 1;
            end
            if value7 == 1
                N = N + 64;
            end
            if value8 == 1
                N = N + 262144;
            end
            if value10 == 1
                N = N + 1;
            end
            %disp(N)
            numImages = 896; % 图像数量
            features = zeros(numImages, N); % N 是特征向量的维度，需要根据你的特征提取函数确定
            dmos1 = zeros(numImages, 1);
            % 提取特征并记录DMOS分数
            for i = 1:numImages
                res = i/numImages*100;
                disp(res)
                f = [];
                fullPath = fullfile(imageDir, im_names{i}); % 获取完整的图像路径
                images{i} = imread(fullPath);
                %imshow(fullPath);
                %getColorStatistics(images{i})
                if value1 == 1
                    I_gray = rgb2gray(images{i}); % 如果是RGB图像，转换为灰度
                    vec = I_gray(:);
                    f = [f, app.estimateaggdparam(vec)];
                end
                if value2 == 1
                    I_gray = rgb2gray(images{i});
                    blockSize = 32; % 定义块大小
                    f = [f, app.computeEnergyDifference(I_gray, blockSize)];
                end
                if value3 == 1
                    I_gray = rgb2gray(images{i});
                    [Gx, Gy] = imgradientxy(I_gray); % 计算x和y方向的梯度
                    gradientMagnitude = sqrt(Gx.^2 + Gy.^2); % 计算梯度幅度
                    [gamma, sigma] = app.estimateGGDParams(gradientMagnitude);
                    f = [f, gamma, sigma];
                end
                if value4 == 1
                    [m1, m2, m3] = app.rgb2monogenic(images{i});
                    LE = m1.^2 + m2.^2 + m3.^2;
                    f = [f, LE(:)'];
                end
                if value5 == 1
                    m = app.MSCN(images{i});
                    m1 = m(:)';
                    f = [f, m1];
                end
                if value6 == 1
                    f = [f, app.getGlobalContrastFactor(images{i})];
                end
                if value7 == 1
                    f = [f, app.getfeature(images{i})];
                end
                if value8 == 1
                    A = app.getPhaseCongruencyImage(images{i});
                    B = A(:)';
                    f = [f, B];
                end
                if value10 == 1
                    f = [f, app.getDarkChannelFeature(images{i})];
                end
                features(i, :) = f; % 提取特征
                dmos1(i) = dmosScores(i); % 记录DMOS分数
            end
            % splitting the training set and the test set
            numTrain = floor(0.8 * numImages);
            trainIndices = randperm(numImages, numTrain);
            testIndices = setdiff(1:numImages, trainIndices);

            trainFeatures = features(trainIndices, :);
            trainDmos = dmos1(trainIndices);
            testFeatures = features(testIndices, :);
            testDmos = dmos1(testIndices);

            % adding a bias term (intercept) to the training features.
            trainFeaturesWithBias = [ones(size(trainFeatures, 1), 1) trainFeatures];
            % training
            b = regress(trainDmos, trainFeaturesWithBias);
            save('trainedModel.mat', 'b');

            % Predict the quality scores for the test set.
            testFeaturesWithBias = [ones(size(testFeatures, 1), 1) testFeatures];
            predictedDmos = testFeaturesWithBias * b;
            % Evaluate model performance.
            % Calculate the Spearman rank correlation coefficient.
            srcc = corr(predictedDmos, testDmos, 'Type', 'Spearman');

            % Display the evaluation results.
            fprintf('Spearman Rank Correlation Coefficient: %f\n', srcc);
            disp('Successful!!!')
            app.ScheduleEditField_3.Value = "Successful!!!";
        end

        % Button pushed function: QualityPredictionButton
        function QualityPredictionButtonPushed(app, event)
            load('trainedModel.mat')
            Image = imresize(app.im3, [512 512]);
            f = [];
            value1 = app.AsymmetricGeneralizedGaussianDistributionCheckBox_2.Value;
            value2 = app.EnergyDifferenceCheckBox_2.Value;
            value3 = app.GeneralizedGaussianDistributionCheckBox_2.Value;
            value4 = app.EntropyCheckBox_2.Value;
            value5 = app.MeanSubtractedContrastNormalizedCheckBox_2.Value;
            value6 = app.GlobalContrastCheckBox_2.Value;
            value7 = app.LocalBinaryPatternCheckBox_2.Value;
            value8 = app.PhaseCongruencyCheckBox_2.Value;
            value9 = app.VisualNeuronMatrixCheckBox_2.Value;
            value10 = app.DarkChannelCheckBox_2.Value;
            if value1 == 1
                I_gray = rgb2gray(Image); % 如果是RGB图像，转换为灰度
                vec = I_gray(:);
                f = [f, app.estimateaggdparam(vec)];
            end
            if value2 == 1
               I_gray = rgb2gray(app.im3);
               blockSize = 32; % 定义块大小
               f = [f, app.computeEnergyDifference(I_gray, blockSize)];
            end
            if value3 == 1
                    I_gray = rgb2gray(Image);
                    [Gx, Gy] = imgradientxy(I_gray); % 计算x和y方向的梯度
                    gradientMagnitude = sqrt(Gx.^2 + Gy.^2); % 计算梯度幅度
                    [gamma, sigma] = app.estimateGGDParams(gradientMagnitude);
                    f = [f, gamma, sigma];
            end
            if value4 == 1
                [m1, m2, m3] = app.rgb2monogenic(app.im);
                LE = m1.^2 + m2.^2 + m3.^2;
                f = [f, LE(:)'];
            end
            if value5 == 1
                m = app.MSCN(app.im);
                m1 = m(:)';
                f = [f, m1];
            end
            if value6 == 1
                f = [f, app.getGlobalContrastFactor(Image)];
            end
            if value7 == 1
               f = [f, app.getfeature(Image)];
            end
             if value8 == 1
                A = app.getPhaseCongruencyImage(app.im);
                B = A(:)';
                f = [f, B];
            end
            if value10 == 1
               f = [f, app.getDarkChannelFeature(Image)];
            end
            testFeaturesWithBias = [ones(size(f, 1), 1) f];
            s = 0;
            if value9 == 1
                    
                    load('D:\VNM\VNM-main\VNM.mat'); % load the VNM,
                    load('D:\VNM\VNM-main\net.mat'); %load the net
    
                    patchSize = sqrt(size(VNM,2)/3);%VNM矩阵的列数除以3再求平方根，块的大小：16
                    patchDim = size(VNM,2);%VNM矩阵的列数，768
                    
                    %%%%%% DIVIDING EACH IMAGE INTO BLOCKS将每一个图像分成块 %%%%%%
                    startPosition = 1;
                    %Id=rgb2gray(Id);%将彩色图像转换成grayimage图像
                    sizeY = size(app.im,1); sizeX = size(app.im,2);%获取输入图像的宽度和高度
                    gridY = startPosition : patchSize : sizeY-patchSize; %1:496,间隔为16
                    gridX = startPosition : patchSize : sizeX-patchSize; %1:496,间隔为16
                    
                    Y = length(gridY);  X = length(gridX);%获取长度
                    
                    Xd = zeros(patchDim, Y*X);%创建一个768行，961列的0矩阵
                    Xd1 = zeros(256, Y*X);%创建一个256行，961列的0矩阵
                    
                    ij = 0;
                    ij1=0;
                    
                    for i = gridY
                        for j = gridX
                            ij = ij+1; 
                            Xd(:,ij) = reshape( app.im(i:i+patchSize-1, j:j+patchSize-1,1:3), [patchDim 1] );
                        end
                    end
                    
                    Xd = double(Xd);%转换成双精度数组
                    mXd = mean(Xd); %求矩阵每一列的平均值   
                    varXd=var(Xd);%求矩阵每一列的方差
                    meXd = mean(mXd);%求行向量的平均值
                    
                    %mean value
                    % remove mean value去除平均值
                    Xd = (Xd-ones(size(Xd,1),1)*meXd);
                    
                    B2 = VNM*Xd;%相乘求特征
                    B=std(B2,0,2);%计算每一行的standard标准差
                    %READ A DISTORTED IMAGE 
                    %Features=VNM_NN(app, app.im, VNM);
                    s  = sim(net,B);
            end
            s2 = s(1);

            if value1 == 0 && value2 == 0 && value3 == 0 && value4 == 0 && value5 == 0 && value6 == 0 && value7 == 0 && value8 == 0 && value10 == 0
                s1 = 0;
                app.ScoreTest = s2;
            else
                x = size(testFeaturesWithBias);
                y = size(b);
                if x(2) > y(1)
                    x = x(1:y(1));
                    s1 = x * b;
                    app.ScoreTest = (s1 + s2) / 2;
                elseif x(2) < y(1)
                    y = y(1:x(1));
                    s1 = testFeaturesWithBias * y;
                    app.ScoreTest = (s1 + s2) / 2;
                else
                    s1 = testFeaturesWithBias * b;
                    app.ScoreTest = (s1 + s2) / 2;
                end
            end
            score_temp = 1 / (1 + exp(-mean(app.ScoreTest)));
            score = num2str(score_temp);
         
            if app.ScoreTest == 0
                app.ScoreEditField_3.FontColor=[1.00,0.00,0.00];
                app.ScoreEditField_3.Value = "请选择NSS特征";
            else
                app.ScoreEditField_3.FontColor=[0.00,0.00,0.00];
                app.ScoreEditField_3.Value = score;
            end
            clear;
        end

        % Value changed function: EnergyDifferenceCheckBox_2
        function EnergyDifferenceCheckBox_2ValueChanged(app, event)
            value = app.EnergyDifferenceCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: GeneralizedGaussianDistributionCheckBox_2
        function GeneralizedGaussianDistributionCheckBox_2ValueChanged(app, event)
            value = app.GeneralizedGaussianDistributionCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: EntropyCheckBox_2
        function EntropyCheckBox_2ValueChanged(app, event)
            value = app.EntropyCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: 
        % MeanSubtractedContrastNormalizedCheckBox_2
        function MeanSubtractedContrastNormalizedCheckBox_2ValueChanged(app, event)
            value = app.MeanSubtractedContrastNormalizedCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: GlobalContrastCheckBox_2
        function GlobalContrastCheckBox_2ValueChanged(app, event)
            value = app.GlobalContrastCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: LocalBinaryPatternCheckBox_2
        function LocalBinaryPatternCheckBox_2ValueChanged(app, event)
            value = app.LocalBinaryPatternCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: PhaseCongruencyCheckBox_2
        function PhaseCongruencyCheckBox_2ValueChanged(app, event)
            value = app.PhaseCongruencyCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: VisualNeuronMatrixCheckBox_2
        function VisualNeuronMatrixCheckBox_2ValueChanged(app, event)
            value = app.VisualNeuronMatrixCheckBox_2.Value;
            if value == 1
            end
        end

        % Value changed function: DarkChannelCheckBox_2
        function DarkChannelCheckBox_2ValueChanged(app, event)
            value = app.DarkChannelCheckBox_2.Value;
            if value == 1
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CustomizedFeatureCombinationTab = uitab(app.TabGroup);
            app.CustomizedFeatureCombinationTab.Title = 'Customized Feature Combination';
            app.UIAxes_3 = uiaxes(app.CustomizedFeatureCombinationTab);
            title(app.UIAxes_3, 'Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.FeatureSelectionPanel_2 = uipanel(app.CustomizedFeatureCombinationTab);
            app.FeatureSelectionPanel_2.Title = 'Feature Selection';
            app.EnergyDifferenceCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.EnergyDifferenceCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnergyDifferenceCheckBox_2ValueChanged, true);
            app.EnergyDifferenceCheckBox_2.Text = 'Energy Difference';
            app.AsymmetricGeneralizedGaussianDistributionCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.AsymmetricGeneralizedGaussianDistributionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AsymmetricGeneralizedGaussianDistributionCheckBoxValueChanged, true);
            app.AsymmetricGeneralizedGaussianDistributionCheckBox_2.Text = 'Asymmetric Generalized Gaussian Distribution';
            app.GeneralizedGaussianDistributionCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.GeneralizedGaussianDistributionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GeneralizedGaussianDistributionCheckBox_2ValueChanged, true);
            app.GeneralizedGaussianDistributionCheckBox_2.Text = 'Generalized Gaussian  Distribution';
            app.MeanSubtractedContrastNormalizedCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.MeanSubtractedContrastNormalizedCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @MeanSubtractedContrastNormalizedCheckBox_2ValueChanged, true);
            app.MeanSubtractedContrastNormalizedCheckBox_2.Text = 'Mean Subtracted Contrast Normalized';
            app.LocalBinaryPatternCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.LocalBinaryPatternCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LocalBinaryPatternCheckBox_2ValueChanged, true);
            app.LocalBinaryPatternCheckBox_2.Text = 'Local Binary Pattern';
            app.EntropyCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.EntropyCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EntropyCheckBox_2ValueChanged, true);
            app.EntropyCheckBox_2.Text = 'Entropy';
            app.GlobalContrastCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.GlobalContrastCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GlobalContrastCheckBox_2ValueChanged, true);
            app.GlobalContrastCheckBox_2.Text = 'Global Contrast';
            app.VisualNeuronMatrixCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.VisualNeuronMatrixCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VisualNeuronMatrixCheckBox_2ValueChanged, true);
            app.VisualNeuronMatrixCheckBox_2.Text = 'Visual  Neuron Matrix';
            app.PhaseCongruencyCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.PhaseCongruencyCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PhaseCongruencyCheckBox_2ValueChanged, true);
            app.PhaseCongruencyCheckBox_2.Text = 'Phase Congruency';
            app.DarkChannelCheckBox_2 = uicheckbox(app.FeatureSelectionPanel_2);
            app.DarkChannelCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DarkChannelCheckBox_2ValueChanged, true);
            app.DarkChannelCheckBox_2.Text = 'Dark Channel';
            app.ScheduleEditField_3Label = uilabel(app.CustomizedFeatureCombinationTab);
            app.ScheduleEditField_3Label.Text = 'Schedule';
            app.ScheduleEditField_3 = uieditfield(app.CustomizedFeatureCombinationTab, 'text');
            app.ScoreEditField_3Label = uilabel(app.CustomizedFeatureCombinationTab);
            app.ScoreEditField_3Label.Text = 'Score';
            app.ScoreEditField_3 = uieditfield(app.CustomizedFeatureCombinationTab, 'text');
            app.LoadImageButton = uibutton(app.CustomizedFeatureCombinationTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.QualityPredictionButton = uibutton(app.CustomizedFeatureCombinationTab, 'push');
            app.QualityPredictionButton.ButtonPushedFcn = createCallbackFcn(app, @QualityPredictionButtonPushed, true);
            app.QualityPredictionButton.Text = 'Quality Prediction';
            app.trainButton_2 = uibutton(app.CustomizedFeatureCombinationTab, 'push');
            app.trainButton_2.ButtonPushedFcn = createCallbackFcn(app, @trainButton_2Pushed, true);
            app.trainButton_2.Text = 'train';
            app.RecommendedFeatureCombinationTab = uitab(app.TabGroup);
            app.RecommendedFeatureCombinationTab.Title = 'Recommended Feature Combination';
            app.UIAxes = uiaxes(app.RecommendedFeatureCombinationTab);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton_2 = uibutton(app.RecommendedFeatureCombinationTab, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_2Pushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.QualityPredictionButton_2 = uibutton(app.RecommendedFeatureCombinationTab, 'push');
            app.QualityPredictionButton_2.ButtonPushedFcn = createCallbackFcn(app, @QualityPredictionButton_2Pushed, true);
            app.QualityPredictionButton_2.Text = 'Quality Prediction';
            app.ScoreEditFieldLabel = uilabel(app.RecommendedFeatureCombinationTab);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.RecommendedFeatureCombinationTab, 'text');
            app.Tree = uitree(app.RecommendedFeatureCombinationTab, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.OPTION1Node = uitreenode(app.Tree);
            app.OPTION1Node.Text = 'OPTION 1';
            app.AsymmetricGeneralizedGaussianDistributionNode_2 = uitreenode(app.OPTION1Node);
            app.AsymmetricGeneralizedGaussianDistributionNode_2.Text = 'Asymmetric Generalized Gaussian Distribution';
            app.LocalBinaryPatternNode_2 = uitreenode(app.OPTION1Node);
            app.LocalBinaryPatternNode_2.Text = 'Local Binary Pattern';
            app.OPTION2Node = uitreenode(app.Tree);
            app.OPTION2Node.Text = 'OPTION 2';
            app.EntropyNode = uitreenode(app.OPTION2Node);
            app.EntropyNode.Text = 'Entropy';
            app.GlobalContrastNode = uitreenode(app.OPTION2Node);
            app.GlobalContrastNode.Text = 'Global Contrast';
            app.EnergyDifferenceNode = uitreenode(app.OPTION2Node);
            app.EnergyDifferenceNode.Text = 'Energy Difference';
            app.AsymmetricGeneralizedGaussianDistributionNode = uitreenode(app.OPTION2Node);
            app.AsymmetricGeneralizedGaussianDistributionNode.Text = 'Asymmetric Generalized Gaussian Distribution';
            app.LocalBinaryPatternNode = uitreenode(app.OPTION2Node);
            app.LocalBinaryPatternNode.Text = 'Local Binary Pattern';
            app.PhaseCongruencyNode = uitreenode(app.OPTION2Node);
            app.PhaseCongruencyNode.Text = 'Phase Congruency';
            app.OPTION3Node = uitreenode(app.Tree);
            app.OPTION3Node.Text = 'OPTION 3';
            app.VisualNeuronMatrixNode = uitreenode(app.OPTION3Node);
            app.VisualNeuronMatrixNode.Text = 'Visual  Neuron Matrix';
            app.OPTION4Node = uitreenode(app.Tree);
            app.OPTION4Node.Text = 'OPTION 4';
            app.GeneralizedGaussianDistributionNode = uitreenode(app.OPTION4Node);
            app.GeneralizedGaussianDistributionNode.Text = 'Generalized Gaussian  Distribution';
            app.AsymmetricGeneralizedGaussianDistributionNode_3 = uitreenode(app.OPTION4Node);
            app.AsymmetricGeneralizedGaussianDistributionNode_3.Text = 'Asymmetric Generalized Gaussian  Distribution';
            app.MeanSubtractedContrastNormalizedNode = uitreenode(app.OPTION4Node);
            app.MeanSubtractedContrastNormalizedNode.Text = 'Mean Subtracted Contrast Normalized';
            app.DefaultSelectionLabel = uilabel(app.RecommendedFeatureCombinationTab);
            app.DefaultSelectionLabel.Text = 'Default Selection';
            app.DirectlyUsableIQAMethodsTab = uitab(app.TabGroup);
            app.DirectlyUsableIQAMethodsTab.Title = 'Directly Usable IQA Methods';
            app.UIAxes_2 = uiaxes(app.DirectlyUsableIQAMethodsTab);
            title(app.UIAxes_2, 'Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadImageButton_3 = uibutton(app.DirectlyUsableIQAMethodsTab, 'push');
            app.LoadImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_3Pushed, true);
            app.LoadImageButton_3.Text = 'Load Image';
            app.ScoreEditField_2Label = uilabel(app.DirectlyUsableIQAMethodsTab);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditField_2 = uieditfield(app.DirectlyUsableIQAMethodsTab, 'text');
            app.QualityPredictionButton_3 = uibutton(app.DirectlyUsableIQAMethodsTab, 'push');
            app.QualityPredictionButton_3.ButtonPushedFcn = createCallbackFcn(app, @QualityPredictionButton_3Pushed, true);
            app.QualityPredictionButton_3.Text = 'Quality Prediction';
            app.IQAMethodButtonGroup = uibuttongroup(app.DirectlyUsableIQAMethodsTab);
            app.IQAMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IQAMethodButtonGroupSelectionChanged, true);
            app.IQAMethodButtonGroup.Title = 'IQA Method';
            app.BRISQUEButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.BRISQUEButton.Text = 'BRISQUE';
            app.BRISQUEButton.Value = true;
            app.NIQEButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.NIQEButton.Text = 'NIQE';
            app.PIQUEButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.PIQUEButton.Text = 'PIQUE';
            app.DBCNNButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.DBCNNButton.Text = 'DB-CNN';
            app.dipIQButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.dipIQButton.Text = 'dipIQ';
            app.OGIQAButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.OGIQAButton.Text = 'OG-IQA';
            app.ILNIQEButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.ILNIQEButton.Text = 'ILNIQE';
            app.GWHGLBPButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.GWHGLBPButton.Text = 'GWH-GLBP';
            app.MEONButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.MEONButton.Text = 'MEON';
            app.DIBRButton = uiradiobutton(app.IQAMethodButtonGroup);
            app.DIBRButton.Text = 'DIBR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIQA_CNSS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end